classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ModeButtonGroup  matlab.ui.container.ButtonGroup
        wavButton        matlab.ui.control.RadioButton
        syllableButton   matlab.ui.control.RadioButton
        elementButton    matlab.ui.control.RadioButton
        forward          matlab.ui.control.Button
        backward         matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        files % Description
        idx
        mode
    end
    
    methods (Access = private)
        
        function spec(app)
            
            [y,fs] = audioread(app.files{app.idx});
            draw.appspec(app.UIAxes,y,fs);
            
        end
        
        function line(app)
            
            switch app.mode
                case 1 
                    color = 'grey'
                case 2
                    color = 'red'
                case 3
                    color = 'blue'
            end
            
            drawline(app.UIAxes,'Deletable','true','Color',color,'InteractionsAllowed','all')
        end
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dir)
            app.files = extract.filename(wavfolder,'*.wav');
            app.idx = 1;
            app.spec;
        end

        % Button pushed function: forward
        function forwardButtonPushed(app, event)
            app.idx = app.idx - 1;
            app.spec;
        end

        % Button pushed function: backward
        function backwardButtonPushed(app, event)
            app.idx = app.idx + 1;
            app.spec;
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModeButtonGroup.SelectedObject;
        
            switch selectedButton
                
                case 'wav'
                    app.mode = 1;
                case 'syllable'
                    app.mode = 2;
                case 'element'
                    app.mode = 3;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = '   Mode';
            app.wavButton = uiradiobutton(app.ModeButtonGroup);
            app.wavButton.Text = 'wav';
            app.wavButton.Value = true;
            app.syllableButton = uiradiobutton(app.ModeButtonGroup);
            app.syllableButton.Text = 'syllable';
            app.elementButton = uiradiobutton(app.ModeButtonGroup);
            app.elementButton.Text = 'element';
            app.forward = uibutton(app.UIFigure, 'push');
            app.forward.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forward.Text = '↑';
            app.backward = uibutton(app.UIFigure, 'push');
            app.backward.ButtonPushedFcn = createCallbackFcn(app, @backwardButtonPushed, true);
            app.backward.Text = '↓';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end