classdef extendPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        relativetotheselectedsyllableSpinnerLabel  matlab.ui.control.Label
        Spinner   matlab.ui.control.Spinner
        UIAxes    matlab.ui.control.UIAxes
        UIAxes2   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        idx % Description
        sylinf
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, idx, sylinf)
         
            app.idx = idx;
            app.sylinf = sylinf;
            fs = 32000;
            value = 0;
            yplt = app.sylinf(app.idx + value).yplt;
            sptimesplt = app.sylinf(app.idx + value).sptimesplt;
            
           % app.UIAxes.AutoResizeChildren = 'off';
            
            
            draw.appspec(app.UIAxes,yplt,fs);
            title(app.UIAxes,sprintf('%s-%u',app.sylinf(app.idx).sound,app.sylinf(app.idx+value).number));
            
           % ax2 = subplot(2,1,2,'Parent',app.Panel);
           % plot(ax2,[1,2,3,4,5,6,7,8,9,10])
            draw.appraster(app.UIAxes2,sptimesplt,yplt,fs);


        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            
            value = app.Spinner.Value;


            fs = 32000;
            yplt = app.sylinf(app.idx + value).yplt;
            sptimesplt = app.sylinf(app.idx + value).sptimesplt;
            
           % app.UIAxes.AutoResizeChildren = 'off';
            
            
            draw.appspec(app.UIAxes,yplt,fs);
            title(app.UIAxes,sprintf('%s-%u',app.sylinf(app.idx).sound,app.sylinf(app.idx+value).number));
            
           % ax2 = subplot(2,1,2,'Parent',app.Panel);
           % plot(ax2,[1,2,3,4,5,6,7,8,9,10])
            draw.appraster(app.UIAxes2,sptimesplt,yplt,fs);
        end

        % Callback function
        function UIAxesButtonDown(app, event)
         
        end

        % Callback function
        function PanelSizeChanged(app, event)
            fs = 32000;
            value = 0;
            yplt = app.sylinf(app.idx + value).yplt;
            sptimesplt = app.sylinf(app.idx + value).sptimesplt;
            ax = app.Panel;
            app.Panel.AutoResizeChildren = 'off';
            
            ax1 = subplot(2,1,1,'Parent',app.Panel);
            draw.appspec(ax1,yplt,fs);
            title(ax1,sprintf('%s-%u',app.sylinf(app.idx).sound,app.sylinf(app.idx+value).number));
            
            ax2 = subplot(2,1,2,'Parent',app.Panel);
            draw.appraster(ax2,sptimesplt,yplt,fs);
          
            axis off
            box off
            colorbar
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.relativetotheselectedsyllableSpinnerLabel = uilabel(app.UIFigure);
            app.relativetotheselectedsyllableSpinnerLabel.Text = 'relative to the selected syllable';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = extendPlot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end