classdef manClus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Button_L    matlab.ui.control.Button
        Button_R    matlab.ui.control.Button
        EditField   matlab.ui.control.NumericEditField
        Label       matlab.ui.control.Label
        SaveButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sylnum % the number/id of a syllable
        frag 
        clustercollect % collect existing cluster number
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, frag)
            % manClus means manually cluster syllable/elements
            app.sylnum = 1; % initial value is zero
            app.clustercollect = [];
            app.frag = frag;
            
            for idx = 1: length(frag)
                app.frag(idx).cluster = 0;
            end
            
            y = app.frag(app.sylnum).y;
            fs = 32000;
            draw.appspec(app.UIAxes,y,fs);
            colormap(app.UIAxes,'default') 
            app.Label.Text = sprintf('Type--%u',app.frag(app.sylnum).cluster); 
        end

        % Button pushed function: Button_L
        function Button_LPushed(app, event)
            app.sylnum = app.sylnum - 1;
            y =  app.frag(app.sylnum).y;
            fs = 32000;
            draw.appspec(app.UIAxes,y,fs);
            colormap(app.UIAxes,'default') 
            app.Label.Text = sprintf('Type--%u',app.frag(app.sylnum).cluster); 
        end

        % Button pushed function: Button_R
        function Button_RPushed(app, event)
            app.sylnum = app.sylnum + 1;
            %%% not safe
            value = app.EditField.Value;
            app.frag(app.sylnum).cluster = value;   % assign the cluster value to the frag struct
            
            %%% until above
            y =  app.frag(app.sylnum).y;
            fs = 32000;
            draw.appspec(app.UIAxes,y,fs);
            colormap(app.UIAxes,'default') 
            app.Label.Text = sprintf('Type--%u',app.frag(app.sylnum).cluster); 
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.frag(app.sylnum).cluster = value;   % assign the cluster value to the frag struct
            app.Label.Text = sprintf('Type--%u',value);  % ????????????
            if ~ismember(value,app.clustercollect)
                y =  app.frag(app.sylnum).y;
                fs = 32000;
                
                % draw a figure
                figure;
                draw.spec(y,fs);
                colormap(app.UIAxes,'default') 
                title(app.Label.Text);
               % autoArrangeFigures;
                
                app.clustercollect = [app.clustercollect,value];
                
            end
                
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
           frag = app.frag;
           nowtime = datestr(datetime('now'),'yyyy-mmm-dd-HH-MM-SS');
           save(sprintf('fragManclustered-%s.mat',nowtime),'frag');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button_L = uibutton(app.UIFigure, 'push');
            app.Button_L.ButtonPushedFcn = createCallbackFcn(app, @Button_LPushed, true);
            app.Button_L.Text = '前';
            app.Button_R = uibutton(app.UIFigure, 'push');
            app.Button_R.ButtonPushedFcn = createCallbackFcn(app, @Button_RPushed, true);
            app.Button_R.Text = '后';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manClus(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end