classdef manFindSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        CheckBox      matlab.ui.control.CheckBox
        CheckBox_2    matlab.ui.control.CheckBox
        CheckBox_3    matlab.ui.control.CheckBox
        CheckBox_4    matlab.ui.control.CheckBox
        CheckBox_5    matlab.ui.control.CheckBox
        CheckBox_6    matlab.ui.control.CheckBox
        Button        matlab.ui.control.Button
        CheckBox_7    matlab.ui.control.CheckBox
        CheckBox_8    matlab.ui.control.CheckBox
        CheckBox_9    matlab.ui.control.CheckBox
        CheckBox_10   matlab.ui.control.CheckBox
        Button_2      matlab.ui.control.Button
        OutputButton  matlab.ui.control.Button
        UIAxes        matlab.ui.control.UIAxes
        UIAxes_2      matlab.ui.control.UIAxes
        UIAxes_4      matlab.ui.control.UIAxes
        UIAxes_3      matlab.ui.control.UIAxes
        UIAxes_6      matlab.ui.control.UIAxes
        UIAxes_5      matlab.ui.control.UIAxes
        UIAxes_7      matlab.ui.control.UIAxes
        UIAxes_8      matlab.ui.control.UIAxes
        UIAxes_9      matlab.ui.control.UIAxes
        UIAxes_10     matlab.ui.control.UIAxes
        UIAxes_11     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        info % sylinf or eleinf
        batch % batch number
        picked 
        count
        raw
        outputstate
    end
    
    methods (Access = public)
        
        function drawbatch(app)
            
            draw.appspec(app.UIAxes_2, app.info((app.batch-1)*10+1).y,app.info((app.batch-1)*10+1).fs);
            title(app.UIAxes_2,sprintf('%s-%u',app.info((app.batch-1)*10+1).songname,app.info((app.batch-1)*10+1).fragid));
            
            draw.appspec(app.UIAxes_3, app.info((app.batch-1)*10+2).y,app.info((app.batch-1)*10+2).fs);
            title(app.UIAxes_3,sprintf('%s-%u',app.info((app.batch-1)*10+2).songname,app.info((app.batch-1)*10+2).fragid));
            
            draw.appspec(app.UIAxes_4, app.info((app.batch-1)*10+3).y,app.info((app.batch-1)*10+3).fs);
            title(app.UIAxes_4,sprintf('%s-%u',app.info((app.batch-1)*10+3).songname,app.info((app.batch-1)*10+3).fragid));
            
            draw.appspec(app.UIAxes_5, app.info((app.batch-1)*10+4).y,app.info((app.batch-1)*10+4).fs);
            title(app.UIAxes_5,sprintf('%s-%u',app.info((app.batch-1)*10+4).songname,app.info((app.batch-1)*10+4).fragid));
            
            draw.appspec(app.UIAxes_6, app.info((app.batch-1)*10+5).y,app.info((app.batch-1)*10+5).fs);
            title(app.UIAxes_6,sprintf('%s-%u',app.info((app.batch-1)*10+5).songname,app.info((app.batch-1)*10+5).fragid));
            
            draw.appspec(app.UIAxes_7, app.info((app.batch-1)*10+6).y,app.info((app.batch-1)*10+6).fs);
            title(app.UIAxes_7,sprintf('%s-%u',app.info((app.batch-1)*10+6).songname,app.info((app.batch-1)*10+6).fragid));
            
            draw.appspec(app.UIAxes_8, app.info((app.batch-1)*10+7).y,app.info((app.batch-1)*10+7).fs);
            title(app.UIAxes_8,sprintf('%s-%u',app.info((app.batch-1)*10+7).songname,app.info((app.batch-1)*10+7).fragid));
            
            draw.appspec(app.UIAxes_9, app.info((app.batch-1)*10+8).y,app.info((app.batch-1)*10+8).fs);
            title(app.UIAxes_9,sprintf('%s-%u',app.info((app.batch-1)*10+8).songname,app.info((app.batch-1)*10+8).fragid));
            
            draw.appspec(app.UIAxes_10, app.info((app.batch-1)*10+9).y,app.info((app.batch-1)*10+9).fs);
            title(app.UIAxes_10,sprintf('%s-%u',app.info((app.batch-1)*10+9).songname,app.info((app.batch-1)*10+9).fragid));
            
            draw.appspec(app.UIAxes_11, app.info((app.batch-1)*10+10).y,app.info((app.batch-1)*10+10).fs);
            title(app.UIAxes_11,sprintf('%s-%u',app.info((app.batch-1)*10+10).songname,app.info((app.batch-1)*10+10).fragid));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, info, banker)
            app.raw = info; % raw for picking
            app.info = stimuli(info).prepro;  % info for drawing
            app.batch = 1;
            app.count = 0;
            
            if ~isfield(app.info,'fs')
                for w = 1: length(app.info)
                    app.info(w).fs = 32000;
                end
            end

           
            draw.appspec(app.UIAxes, app.info(banker).y, app.info(banker).fs);
            title(app.UIAxes,sprintf('%s-%u',app.info(banker).songname,app.info(banker).fragid),'Color','red');
            
            app.drawbatch;
            
            % initialize app.picked
             f = fieldnames(app.raw)';
             f{2,1} = {};
             app.picked = struct(f{:});
             app.outputstate = 0;
            
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.batch = app.batch + 1;
            app.drawbatch;
            set(app.CheckBox,'Value',0)
            set(app.CheckBox_2,'Value',0)
            set(app.CheckBox_3,'Value',0)
            set(app.CheckBox_4,'Value',0)
            set(app.CheckBox_5,'Value',0)
            set(app.CheckBox_6,'Value',0)
            set(app.CheckBox_7,'Value',0)
            set(app.CheckBox_8,'Value',0)
            set(app.CheckBox_9,'Value',0)
            set(app.CheckBox_10,'Value',0)
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 1
                app.count = app.count + 1;
                app.picked(app.count) = app.raw((app.batch-1)*10 + 1);
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            if value == 1
                app.count = app.count + 1;
                app.picked(app.count) = app.raw((app.batch-1)*10 + 2);
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
             if value == 1
                app.count = app.count + 1;
                app.picked(app.count) = app.raw((app.batch-1)*10 + 3);
            end
            
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
             if value == 1
                app.count = app.count + 1;
                app.picked(app.count) = app.raw((app.batch-1)*10 + 4);
            end
            
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
             if value == 1
                app.count = app.count + 1;
                app.picked(app.count) = app.raw((app.batch-1)*10 + 5);
            end
            
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
             if value == 1
                app.count = app.count + 1;
                app.picked(app.count) = app.raw((app.batch-1)*10 + 6);
            end
            
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            if value == 1
                app.count = app.count + 1;
                app.picked(app.count) = app.raw((app.batch-1)*10 + 7);
            end
            
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            value = app.CheckBox_8.Value;
            if value == 1
                app.count = app.count + 1;
                app.picked(app.count) = app.raw((app.batch-1)*10 + 8);
            end
            
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            value = app.CheckBox_9.Value;
             if value == 1
                app.count = app.count + 1;
                app.picked(app.count) = app.raw((app.batch-1)*10 + 9);
            end
            
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            value = app.CheckBox_10.Value;
            if value == 1
                app.count = app.count + 1;
                app.picked(app.count) = app.raw((app.batch-1)*10 + 10);
            end
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.batch = app.batch + 1;
            app.drawbatch;
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            app.outputstate = 1;
            assignin('base','picked',app.picked);
          
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_6 = uicheckbox(app.UIFigure);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = ' 下一组';
            app.CheckBox_7 = uicheckbox(app.UIFigure);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_8 = uicheckbox(app.UIFigure);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_9 = uicheckbox(app.UIFigure);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_10 = uicheckbox(app.UIFigure);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = ' 上一组';
            app.OutputButton = uibutton(app.UIFigure, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Title')
            xlabel(app.UIAxes_7, 'X')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Title')
            xlabel(app.UIAxes_8, 'X')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, 'Title')
            xlabel(app.UIAxes_9, 'X')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_10 = uiaxes(app.UIFigure);
            title(app.UIAxes_10, 'Title')
            xlabel(app.UIAxes_10, 'X')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_11 = uiaxes(app.UIFigure);
            title(app.UIAxes_11, 'Title')
            xlabel(app.UIAxes_11, 'X')
            zlabel(app.UIAxes_11, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manFindSim(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end