classdef manSeg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ModeButtonGroup  matlab.ui.container.ButtonGroup
        wavButton        matlab.ui.control.RadioButton
        syllableButton   matlab.ui.control.RadioButton
        elementButton    matlab.ui.control.RadioButton
        editButton       matlab.ui.control.RadioButton
        stopButton       matlab.ui.control.Button
        backwardButton   matlab.ui.control.Button
        forwardButton    matlab.ui.control.Button
        ExtractButton    matlab.ui.control.Button
        OutputButton     matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % for all files in the folder
        outputstate
        output
        files
        wavinf
        sylinf
        eleinf
        win % wav inf num
        sin
        ein
        % for current file
        idx % Description
        wav
        syllable
        element
        rawy
        fs
        name
        wn
        sn
        en
        % ui control
         mode
        stopstate
    end
    
    methods (Access = public)
        
        function spec(app)
            [app.rawy,app.fs] = audioread(app.files{app.idx});
            [~,app.name,~] = fileparts(app.files{app.idx});
            draw.appspec(app.UIAxes,app.rawy, app.fs);
            title(app.UIAxes,sprintf('%s',app.name));
        end

        
        function  settleup(app)   %% 结算一个wav里的所有信息
            app.outputstate = 0; % controler for waitfor
            dbstop if error
            for a = 1: length(app.wav)
                if  isvalid(app.wav{a})
                    app.win = app.win + 1;
                    coordi = app.wav{a}.Position(:,1);
                    app.wavinf(app.win).initial = coordi(1)*app.fs;
                    app.wavinf(app.win).terminal = coordi(2)*app.fs;
                    app.wavinf(app.win).songname = app.name;
                    app.wavinf(app.win).fragid = a;
                    app.wavinf(app.win).y = app.rawy(coordi(1)*app.fs:coordi(2)*app.fs);
                end
            end
            for b = 1: length(app.syllable)
                if  isvalid(app.syllable{b})&& ~isempty(app.syllable{b}.Position)
                    app.sin = app.sin + 1;
                    coordi = app.syllable{b}.Position(:,1);
                    app.sylinf(app.sin).initial = coordi(1)*app.fs;
                    app.sylinf(app.sin).terminal = coordi(2)*app.fs;
                    app.sylinf(app.sin).songname = app.name;
                    app.sylinf(app.sin).fragid = b;
                    app.sylinf(app.sin).y = app.rawy(coordi(1)*app.fs:coordi(2)*app.fs);
                    app.sylinf(app.sin).fs = app.fs;
                    if b ~= 1
                        app.sylinf(app.sin).pregap = (app.sylinf(app.sin).initial  - app.sylinf(app.sin-1).terminal)/app.fs;
                    else
                        app.sylinf(app.sin).pregap = inf; % pre-gap duration
                    end
                    app.sylinf(app.sin).dur = length(app.sylinf(app.sin).y)/app.fs;
                end
                
            end
            for c = 1: length(app.element)
                if  isvalid(app.element{c}) && ~isempty(app.element{c}.Position)
                    app.ein = app.ein + 1;
                   % disp(app.element{c}.Position(:,1))
                    coordi = app.element{c}.Position(:,1);
                    app.eleinf(app.ein).initial = coordi(1)*app.fs;
                    app.eleinf(app.ein).terminal = coordi(2)*app.fs;
                    app.eleinf(app.ein).songname = app.name;
                    app.eleinf(app.ein).fragid = c;
                    app.eleinf(app.ein).y = app.rawy(coordi(1)*app.fs:coordi(2)*app.fs);
                    app.eleinf(app.ein).fs = app.fs;
                    if c ~= 1
                        app.eleinf(app.ein).pregap = (app.eleinf(app.ein).initial  - app.eleinf(app.ein-1).terminal)/app.fs;
                    else
                        app.eleinf(app.ein).pregap = inf; % pre-gap duration
                    end
                    app.eleinf(app.ein).dur = length(app.eleinf(app.ein).y)/app.fs;
                end
            end
            % 全部归零
%             app.wav = {};   
%             app.syllable = {};
%             app.element = {};
            app.wn = 0;
            app.sn = 0;
            app.en = 0;
            
        end
    end
    
    methods (Access = private)
        
        function select(app)
            dbstop if error
            switch app.mode
                case 1
                    color = 'blue'
                    app.wn = app.wn + 1;
                    app.wav{app.wn} = drawline(app.UIAxes,'Color',color,'Deletable',true);
                    %app.wav
                    disp('Why??????????')


                case 2
                    color = 'green'

                    while true
                        
                        disp(app.stopstate)
                        if app.stopstate == 1
                            app.stopstate = 0;
                            break
                        end
                        app.sn = app.sn + 1;
                        app.syllable{app.sn} =drawline(app.UIAxes,'Color',color,'Deletable',true,'LineWidth',2);
                        drawnow()
                    end
                    
                case 3
                    color = 'red'
                     while true
                       
                       if app.stopstate == 1
                           app.stopstate = 0;
                           break 
                       end
                       app.en = app.en + 1;
                       app.element{app.en} = drawline(app.UIAxes,'Color',color,'Deletable',true,'LineWidth',1);
                       drawnow()
                     end
                     
                case 4
                    disp('Edit Mode ')
                    
            end


        end
        
        function extract(app)
           app.settleup;
           wavinf = app.wavinf;
           sylinf = app.sylinf;
           eleinf = app.eleinf;
           nowtime = datestr(datetime('now'),'yyyy-mmm-dd-HH-MM-SS');
           save(sprintf('wavinf-%s.mat',nowtime),'wavinf');
           save(sprintf('sylinf-%s.mat',nowtime),'sylinf');
           save(sprintf('eleinf-%s.mat',nowtime),'eleinf');
       
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dir)
            app.files = extract.filename(dir,'*.wav');
            app.idx = 1;
            app.mode = 1;
            app.stopstate = 0;
            app.wn = 0;
            app.sn = 0;
            app.en = 0;
            app.win = 0;
            app.sin = 0;
            app.ein = 0;
            app.spec;
            app.select;
         
            
        end

        % Button pushed function: forwardButton
        function forwardButtonPushed(app, event)
            app.settleup; % settleup !!
            app.idx = app.idx + 1;
            set(app.ModeButtonGroup,'selectedobject',app.ModeButtonGroup.Buttons(1))
            app.mode = 1;
            app.spec;
            app.select;
        end

        % Button pushed function: backwardButton
        function backwardButtonPushed(app, event)
            app.idx = app.idx - 1;
            set(app.ModeButtonGroup,'selectedobject',app.ModeButtonGroup.Buttons(4))
            app.mode = 4;
            app.spec;
            app.select;
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            disp('wdcsaswcw')
            selectedButton = app.ModeButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'wav'
                    app.mode = 1;
                    app.select;
                case 'syllable'
                    app.mode = 2;
                    app.select;
                    disp('DDDDDD')
                case 'element'
                    app.mode = 3;
                    app.select;
                case 'edit'
                    app.mode = 4;
            end
           
           
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            
            app.stopstate = 1;
            app.mode = 4;
            set(app.ModeButtonGroup,'selectedobject',app.ModeButtonGroup.Buttons(4))
            
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            app.extract;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %app.output = {app.wavinf,app.sylinf,app.eleinf};
           % delete(app)
            
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            app.outputstate = 1;
            app.output = {app.wavinf,app.sylinf,app.eleinf};
            assignin('base','output',app.output)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = '  Mode';
            app.wavButton = uiradiobutton(app.ModeButtonGroup);
            app.wavButton.Text = 'wav';
            app.wavButton.Value = true;
            app.syllableButton = uiradiobutton(app.ModeButtonGroup);
            app.syllableButton.Text = 'syllable';
            app.elementButton = uiradiobutton(app.ModeButtonGroup);
            app.elementButton.Text = 'element';
            app.editButton = uiradiobutton(app.ModeButtonGroup);
            app.editButton.Visible = 'off';
            app.editButton.Text = 'edit';
            app.stopButton = uibutton(app.ModeButtonGroup, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.backwardButton = uibutton(app.UIFigure, 'push');
            app.backwardButton.ButtonPushedFcn = createCallbackFcn(app, @backwardButtonPushed, true);
            app.backwardButton.Text = 'backward';
            app.forwardButton = uibutton(app.UIFigure, 'push');
            app.forwardButton.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forwardButton.Text = 'forward';
            app.ExtractButton = uibutton(app.UIFigure, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = 'Extract';
            app.OutputButton = uibutton(app.UIFigure, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manSeg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end