classdef inspectapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        relativetotheselectedsyllableSpinnerLabel  matlab.ui.control.Label
        relativetotheselectedsyllableSpinner  matlab.ui.control.Spinner
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        idx % Description
        sylinf
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, idx, sylinf)
            app.idx = idx;
            app.sylinf = sylinf;
        end

        % Value changed function: 
        % relativetotheselectedsyllableSpinner
        function relativetotheselectedsyllableSpinnerValueChanged(app, event)
            fs = 32000;
            value = app.relativetotheselectedsyllableSpinner.Value;
            y = app.sylinf(app.idx + value).y;
            myAxe = app.UIAxes;
            [S,F,T] = spectrogram(y,hamming(512),512-round(fs/1e3),512,fs);
            imagesc(myAxe, T, F, log(1+abs(S)) ); %plot the log spectrum
            title(myAxe,sprintf('%s-%u',app.sylinf(app.idx).sound,app.sylinf(app.idx+value).number));
            set(myAxe,'YDir', 'normal');% flip the Y Axis so lower frequencies are at the bottom
            axis off
            box off
            colorbar
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
             fs = 32000;
            value = 0;  %%% the only difference
            y = app.sylinf(app.idx + value).y;
            myAxe = app.UIAxes;
            [S,F,T] = spectrogram(y,hamming(512),512-round(fs/1e3),512,fs);
            imagesc(myAxe, T, F, log(1+abs(S)) ); %plot the log spectrum
            title(myAxe,sprintf('%s-%u',app.sylinf(app.idx).sound,app.sylinf(app.idx+value).number))
            set(myAxe,'YDir', 'normal');% flip the Y Axis so lower frequencies are at the bottom
            axis off
            box off
            colorbar
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.relativetotheselectedsyllableSpinnerLabel = uilabel(app.UIFigure);
            app.relativetotheselectedsyllableSpinnerLabel.Text = 'relative to the selected syllable';
            app.relativetotheselectedsyllableSpinner = uispinner(app.UIFigure);
            app.relativetotheselectedsyllableSpinner.ValueChangedFcn = createCallbackFcn(app, @relativetotheselectedsyllableSpinnerValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Frequency')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inspectapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end